classdef app1 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        TabGroup                 matlab.ui.container.TabGroup
        MainTab                  matlab.ui.container.Tab
        GDPNowcastingAppLabel    matlab.ui.control.Label
        FilesPanel               matlab.ui.container.Panel
        NewVintageStatusLabel    matlab.ui.control.Label
        SampleNewVintageButton   matlab.ui.control.Button
        LoadNewVintageButton     matlab.ui.control.Button
        SampleDataButton         matlab.ui.control.Button
        SampleSpecButton         matlab.ui.control.Button
        LoadOldVintageButton     matlab.ui.control.Button
        SpecFileButton           matlab.ui.control.Button
        SpecStatusLabel          matlab.ui.control.Label
        DataStatusLabel          matlab.ui.control.Label
        ConfigureRun             matlab.ui.container.Panel
        FilterScrollPanel        matlab.ui.container.Panel
        DeselectAllButton        matlab.ui.control.Button
        SelectAllButton          matlab.ui.control.Button
        SeriesFilterNoteLabel    matlab.ui.control.Label
        SeriesFilterLabel        matlab.ui.control.Label
        RunModelButton           matlab.ui.control.Button
        ResultsTab               matlab.ui.container.Tab
        ExportNewsButton         matlab.ui.control.Button
        UITable                  matlab.ui.control.Table
        NewsTextArea             matlab.ui.control.TextArea
        NewsTableLabel           matlab.ui.control.Label
        NowcastPanel             matlab.ui.container.Panel
        NowcastQoQLabel          matlab.ui.control.Label
        NowcastUnitsLabel        matlab.ui.control.Label
        NowcastValueLabel        matlab.ui.control.Label
        NowcastTitleLabel        matlab.ui.control.Label
        FiguresTab               matlab.ui.container.Tab
        TabGroup2                matlab.ui.container.TabGroup
        Fig1Tab                  matlab.ui.container.Tab
        Fig1SeriesDropdown       matlab.ui.control.DropDown
        Fig1SeriesDropdownLabel  matlab.ui.control.Label
        Fig1AxesTransformed      matlab.ui.control.UIAxes
        Fig1AxesRaw              matlab.ui.control.UIAxes
        Fig2Tab                  matlab.ui.container.Tab
        Fig2Axes                 matlab.ui.control.UIAxes
        Fig3Tab                  matlab.ui.container.Tab
        Fig3SeriesDropDown       matlab.ui.control.DropDown
        SeriesLabel              matlab.ui.control.Label
        Fig3AxesBottom           matlab.ui.control.UIAxes
        Fig3AxesTop              matlab.ui.control.UIAxes
        AICopilotTab             matlab.ui.container.Tab
        AIExportButton           matlab.ui.control.Button
        AIResponseTextArea       matlab.ui.control.TextArea
        AIResponseTextAreaLabel  matlab.ui.control.Label
        AIGenerateButton         matlab.ui.control.Button
        AIAPIKeyField            matlab.ui.control.EditField
        AIAPIKeyFieldLabel       matlab.ui.control.Label
        AIPromptDropdown         matlab.ui.control.DropDown
        AIPromptDropdownLabel    matlab.ui.control.Label
        SummaryTable             matlab.ui.container.Tab
        ExportSummarytxtButton   matlab.ui.control.Button
        SummaryTextArea          matlab.ui.control.TextArea
        ModelOutputLabel         matlab.ui.control.Label
    end


    %-------------------------------------------------------------------------------------

    % Properties Created
    properties (Access = private)
        DataFilePath
        SpecFilePath

        OriginalTable

        TimeOriginal
        TimeUpdated

        Spec

        OriginalResults
        UpdatedResults

        OriginalLog
        UpdatedLog

        ModifiedSpec
        CheckboxHandles = {}
        IncludedSeries = []

        XData

        NowcastValue %current GDP nowcast value
        NowcastQuarter % fiscal quarter
        SpecFile            char
        DataFile            char
        BlockCheckboxes % array of CheckBox handles, built by populateFilterPanel
        SeriesCheckboxes cell

        Time double

        NewVintageFilePath
        OldVintageDate      % vintage_old, 'yyyy-mm-dd'
        NewVintageDate       % vintage_new, 'yyyy-mm-dd'
        XOld                 % transformed old-vintage data, for news decomposition
        XNew                 % transformed new-vintage data, for news decomposition

        ModifiedKeepRows    % logical mask: series kept in ModifiedSpec, aligned to app.Spec

        SpecLog         char = ''   % evalc of load_spec  (Table 1)
        SummaryLog      char = ''   % evalc of summarize  (Table 2)
        NowcastLog      char = ''   % evalc of update_nowcast (Nowcast Update + Detail Table)
        NewsExportTable table       % released-filtered news table, for .xlsx export


    end
    %------------------------------------------------------------------------------------

    % custom METHODS
    methods (Access = private)
        %------------------------------------------------------------------------------------------

        % Load SPEC
        %   Loads the USERS SPEC file into Model
        %   Lets USER know if the file has load successfully or not
        function loadSpecFile(app)
            [file, path] = uigetfile({'*.xls;*.xlsx','Excel Files (*.xls, *.xlsx)'}, ...
                'Select Specification File');

            if isequal(file,0)
                return
            end

            app.SpecFilePath = fullfile(path, file);

            try
                app.Spec = load_spec(app.SpecFilePath);
                populateFilterPanel(app);
                app.SpecStatusLabel.Text            = ['✓ ' file];
                app.SpecStatusLabel.FontColor       = [0.13 0.55 0.13];
                populateFiguresDropdowns(app);
                %app.SeriesFilterLabel.Position         = [12 282 120 22];
                %app.SeriesFilterNoteLabel.Position     = [136 282 560 22];
                app.SeriesFilterNoteLabel.Text         = '(uncheck to exclude from modified model)';
            catch ME
                app.SpecStatusLabel.Text            = '✗ Load failed';
                app.SpecStatusLabel.FontColor       = [0.85 0.10 0.10];
                uialert(app.UIFigure, ME.message, 'Spec Load Error');
            end
        end

        %------------------------------------------------------------------------------------------
        function runModel(app)

            if isempty(app.SpecFilePath) || isempty(app.DataFilePath) || isempty(app.NewVintageFilePath)
                uialert(app.UIFigure, ...
                    'Please load a Spec file and both vintage data files first.', ...
                    'Missing Files');
                return
            end

            try
                threshold   = 1e-4;
                sampleStart = datenum('2000-01-01', 'yyyy-mm-dd');

                app.OriginalResults = [];
                app.UpdatedResults  = [];
                if isempty(app.Spec)
                    app.Spec = load_spec(app.SpecFilePath);
                end

                % ── Stage 1: load old vintage ─────────────────────────────────
                app.RunModelButton.Text = 'Loading old vintage...';
                drawnow
                [X_old, ~] = load_data(app.DataFilePath, app.Spec, sampleStart, 1);

                % ── Stage 2: load new vintage ─────────────────────────────────
                app.RunModelButton.Text = 'Loading new vintage...';
                drawnow
                [X_new, Time, Z_new] = load_data(app.NewVintageFilePath, app.Spec, sampleStart, 1);

                app.XOld  = X_old;
                app.XNew  = X_new;
                app.XData = Z_new;
                
                % Capture example_DFM.m-style text (Table 1 + Table 2). Wrapped so a text
                % capture failure can't abort the model run.
                try, app.SpecLog    = evalc('load_spec(app.SpecFilePath);'); catch, app.SpecLog = ''; end
                try, app.SummaryLog = evalc('summarize(X_new, Time, app.Spec, app.NewVintageDate);'); catch, app.SummaryLog = ''; end

                app.ModifiedSpec = buildModifiedSpec(app);

                % ── Stage 3: estimate DFM on full spec ────────────────────────
                app.RunModelButton.Text = 'Estimating DFM (original)...';
                drawnow
                app.OriginalLog = evalc('app.OriginalResults = dfm(X_new, app.Spec, threshold);');
                % ── Stage 4: estimate DFM on modified spec (if different) ──────
                if isequal(app.Spec.Blocks, app.ModifiedSpec.Blocks)
                    app.UpdatedResults = app.OriginalResults;
                    app.UpdatedLog     = app.OriginalLog;
                else
                    app.RunModelButton.Text = 'Estimating DFM (modified)...';
                    drawnow
                    % X_new has full-spec columns; subset to the series ModifiedSpec keeps
                    X_mod = X_new(:, app.ModifiedKeepRows);
                    app.UpdatedLog = evalc('app.UpdatedResults = dfm(X_mod, app.ModifiedSpec, threshold);');
                end
                app.TimeOriginal = Time;
                app.TimeUpdated  = Time;

            catch ME
                uialert(app.UIFigure, ...
                    ['Model run failed: ' ME.message], ...
                    'Run Error');
            end
        end

        %------------------------------------------------------------------------------------------
        % computeGDPProjection
        %   computes GDP

        function y = computeGDPProjection(app, Res, spec)
            %Find GDPC1 in spec
            gdp_idx = find(strcmp('GDPC1', spec.SeriesID));

            if isempty(gdp_idx)
                %Spec does not contain GDPC1, use X_sm(:,1) instead
                warning('GDPC1 not found in Spec. Plotting kalman smoothed series 1 instead.');
                y = Res.X_sm(:,1);
                return
            end

            % Number of current-period factors = total factors across all blocks
            nF = size(spec.Blocks, 2);

            % Common factor projection in original unstandardized units
            % Using formula from FRBNY example_DFM.m lines 74-75
            y = Res.C(gdp_idx, 1:nF) * Res.Z(:,1:nF)' ...
                .*Res.Wx(gdp_idx) + Res.Mx(gdp_idx);

            % transpose column vector to match Time
            y = y(:);


        end


        %------------------------------------------------------------------------------------------
        % PopulateFilterPanel
        function populateFilterPanel(app)

            Spec    = app.Spec;
            nBlocks = size(Spec.Blocks, 2);

            % --- Clear existing contents ---
            delete(app.FilterScrollPanel.Children);
            app.BlockCheckboxes  = matlab.ui.control.CheckBox.empty(1,0);
            app.SeriesCheckboxes = cell(nBlocks, 1);

            % --- Identify the Global column (by name; fall back to column 1) ---
            globalCol = find(strcmpi(Spec.BlockNames, 'Global'), 1);
            if isempty(globalCol)
                globalCol = 1;
            end

            % --- Decide which series to list under each block ---
            %   Global lists ONLY series that belong to no other block; every
            %   other block lists all its members. A series in Global + another
            %   block therefore appears once, under the other block.
            keptSeries = cell(nBlocks, 1);
            for b = 1:nBlocks
                idx = find(Spec.Blocks(:, b) == 1);
                if b == globalCol
                    idx = idx(sum(Spec.Blocks(idx, :), 2) == 1);   % only-Global members
                end
                keptSeries{b} = idx;
            end

            % --- Count total rows ---
            rowH  = 24;
            nRows = 0;
            for b = 1:nBlocks
                nRows = nRows + 1 + numel(keptSeries{b});
            end

            % --- Scrollable grid
            g = uigridlayout(app.FilterScrollPanel, ...
                'RowHeight',     repmat({rowH}, 1, nRows), ...
                'ColumnWidth',   {'1x'}, ...
                'RowSpacing',    0, ...
                'ColumnSpacing', 0, ...
                'Padding',       [4 4 4 4], ...
                'Scrollable',    'on');

            % --- Populate rows ---
            rowIdx = 0;
            for b = 1:nBlocks
                seriesIdxInBlock = keptSeries{b};
                blockName        = Spec.BlockNames{b};

                % Block header checkbox
                rowIdx = rowIdx + 1;
                bCB = uicheckbox(g, ...
                    'Text',       ['▶  ' blockName], ...
                    'Value',      1, ...
                    'FontWeight', 'bold', ...
                    'FontSize',   12, ...
                    'FontColor',  [0.24 0.20 0.54]);
                bCB.Layout.Row    = rowIdx;
                bCB.Layout.Column = 1;
                bCB.UserData        = b;
                bCB.ValueChangedFcn = @(src,evt) onBlockCheckboxChanged(app, src, evt);
                app.BlockCheckboxes(b) = bCB;

                % Series checkboxes
                sCBs = matlab.ui.control.CheckBox.empty(1,0);
                for si = 1:numel(seriesIdxInBlock)
                    sIdx   = seriesIdxInBlock(si);
                    rowIdx = rowIdx + 1;
                    label  = ['        ' Spec.SeriesID{sIdx} '  –  ' Spec.SeriesName{sIdx}];
                    sCB = uicheckbox(g, ...
                        'Text',     label, ...
                        'Value',    1, ...
                        'FontSize', 11);
                    sCB.Layout.Row    = rowIdx;
                    sCB.Layout.Column = 1;
                    sCB.UserData        = [b, sIdx];
                    sCB.ValueChangedFcn = @(src,evt) onSeriesCheckboxChanged(app, src, evt);
                    sCBs(si) = sCB;
                end
                app.SeriesCheckboxes{b} = sCBs;
            end
        end
        %------------------------------------------------------------------------------------------

        function onBlockCheckboxChanged(app, src, ~)
            b = src.UserData;           % block index
            newVal = src.Value;
            sCBs = app.SeriesCheckboxes{b};
            for i = 1:numel(sCBs)
                sCBs(i).Value = newVal;
            end
        end
        %------------------------------------------------------------------------------------------

        function onSeriesCheckboxChanged(app, src, ~)
            b = src.UserData(1);        % block index (first element of UserData)
            updateBlockHeaderState(app, b);
        end

        %------------------------------------------------------------------------------------------

        function updateBlockHeaderState(app, b)
            % Set block checkbox: checked if all series on, unchecked if all off,
            % visually "mixed" (FontColor change) if partial.
            sCBs = app.SeriesCheckboxes{b};
            vals = arrayfun(@(c) c.Value, sCBs);
            bCB  = app.BlockCheckboxes(b);

            if all(vals)
                bCB.Value     = 1;
                bCB.FontColor = [0.24 0.20 0.54];   % full purple = all on
            elseif ~any(vals)
                bCB.Value     = 0;
                bCB.FontColor = [0.24 0.20 0.54];
            else
                % Partial: keep checkbox checked but signal mixed state with gray text
                bCB.Value     = 1;
                bCB.FontColor = [0.55 0.55 0.55];   % gray = indeterminate
            end
        end

        %------------------------------------------------------------------------------------------

        % buildModifiedSpec
        function ModSpec = buildModifiedSpec(app)
            % Reads checkbox state and constructs a filtered Spec.
            % Series with unchecked boxes are removed (row deletion).
            % Block columns that become all-zero are dropped.
            % GDP series is always kept regardless of checkbox state.

            ModSpec = app.Spec;     % start from full spec
            nSeries = numel(ModSpec.SeriesID);
            nBlocks = size(ModSpec.Blocks, 2);

            % --- Identify GDP row (never removed) ---
            gdpID = 'GDPC1';
            gdpRow = find(strcmpi(ModSpec.SeriesID, gdpID), 1);

            % --- Collect excluded series indices from checkboxes ---
            excludeRows = false(nSeries, 1);
            for b = 1:nBlocks
                sCBs = app.SeriesCheckboxes{b};
                for si = 1:numel(sCBs)
                    if sCBs(si).Value == 0
                        absIdx = sCBs(si).UserData(2);   % absolute series row index
                        if absIdx ~= gdpRow              % protect GDP
                            excludeRows(absIdx) = true;
                        end
                    end
                end
            end

            keepRows = ~excludeRows;
            app.ModifiedKeepRows = keepRows; 

            % --- Filter all Spec fields that are per-series ---
            seriesFields = {'SeriesID','SeriesName','Frequency','Units', ...
                'Transformation','UnitsTransformed','Category','Blocks'};
            for f = 1:numel(seriesFields)
                fld = seriesFields{f};
                if isfield(ModSpec, fld)
                    val = ModSpec.(fld);
                    if size(val, 1) == nSeries
                        ModSpec.(fld) = val(keepRows, :);
                    end
                end
            end

            % --- Drop block columns that are now entirely zero ---
            emptyBlocks = ~any(ModSpec.Blocks, 1);  % columns with no loaded series

            % Block 1 (global) must never be dropped — enforce this
            emptyBlocks(1) = false;

            ModSpec.Blocks     = ModSpec.Blocks(:, ~emptyBlocks);
            ModSpec.BlockNames = ModSpec.BlockNames(~emptyBlocks);
        end
        %------------------------------------------------------------------------------------------
        % computeBlockContributions
        function computeBlockContributions(app)
            gdp_idx = find(strcmp('GDPC1', app.Spec.SeriesID));

            if isempty(gdp_idx)
                warning('GDPC1 not found in Spec.');
                gdp_idx = 1;
            end

            nF = size(app.Spec.Blocks, 2);
            Wx_gdp = app.OriginalResults.Wx(gdp_idx);
            contributions = zeros(nF, 1);

            for i = 1:nF
                orig = app.OriginalResults.C(gdp_idx, i) .* app.OriginalResults.Z(:,i) .* Wx_gdp;
                mod  = app.UpdatedResults.C(gdp_idx, i)  .* app.UpdatedResults.Z(:,i)  .* Wx_gdp;
                contributions(i) = mean(orig - mod, 'omitnan');
            end
        end
    
        %------------------------------------------------------------------------------------------

        % EXPORT
        %   Exports Model results
        function exportResults(app)
            [file, path] = uiputfile({'*.xlsx','Excel Workbook (*.xlsx)'}, ...
                'Save Exported Results');

            if isequal(file,0)
                return
            end

            fullFile = fullfile(path, file);

            try
                if ~isempty(app.OriginalResults)
                    % Use actual series names as column headers
                    colNames = matlab.lang.makeValidName(app.Spec.SeriesName);
                    origTable = array2table(app.OriginalResults.X_sm, ...
                        'VariableNames', colNames);
                    writetable(origTable, fullFile, 'Sheet', 'OriginalSmoothedSeries');
                end

                if ~isempty(app.UpdatedResults)
                    colNames = matlab.lang.makeValidName(app.ModifiedSpec.SeriesName);
                    updTable = array2table(app.UpdatedResults.X_sm, ...
                        'VariableNames', colNames);
                    writetable(updTable, fullFile, 'Sheet', 'ModifiedSmoothedSeries');
                end

                uialert(app.UIFigure, 'Export successful.', 'Export Complete', 'Icon', 'success');
            catch ME
                uialert(app.UIFigure, ME.message, 'Export Error');
            end
        end
        %------------------------------------------------------------------------------------------
        % updateResultsTab — Results tab
        %   Populates nowcast value and stat cards after model run
        function updateResultsTab(app)
            if isempty(app.OriginalResults)
                return;
            end

            % Value, units, and QoQ are painted after the nowcast is computed
            % (in RunModelButtonPushed's news-decomposition step), because the
            % nowcast value comes from update_nowcast, not from X_sm. Here we
            % only set the title to the nowcast target quarter.
            if ~isempty(app.TimeOriginal)
                [yr, mo, ~] = datevec(app.TimeOriginal(end));
                mo_t = mo + 3;  yr_t = yr;          % target = one quarter ahead
                if mo_t > 12, mo_t = mo_t - 12; yr_t = yr_t + 1; end
                qtr = ceil(mo_t / 3);
                app.NowcastTitleLabel.Text = sprintf('GDP nowcast — %dQ%d', yr_t, qtr);
            end
        end

        %------------------------------------------------------------------------------------------

        function updateForecastDetailsLog(app)
            if isempty(app.OriginalLog), return; end
            fullLog = [
                "===== ORIGINAL MODEL OUTPUT ====="
                splitlines(string(app.OriginalLog))
                ""
                "===== MODIFIED MODEL OUTPUT ====="
                splitlines(string(app.UpdatedLog))
                ];
            app.SummaryTextArea.Value = cellstr(fullLog); 
        end
        %------------------------------------------------------------------------------------------

        %------------------------------------------------------------------------------------------
        % populateFiguresDropdowns
        %   Called after spec loads and after run — fills Fig1 and Fig3 dropdowns
        function populateFiguresDropdowns(app)
            if isempty(app.Spec)
                return
            end

            % All series for Fig1
            allIDs = app.Spec.SeriesID;
            app.Fig1SeriesDropdown.Items = allIDs;
            if ~isempty(allIDs)
                % Default to INDPRO if present, else first series
                if ismember('INDPRO', allIDs)
                    app.Fig1SeriesDropdown.Value = 'INDPRO';
                else
                    app.Fig1SeriesDropdown.Value = allIDs{1};
                end
            end

            % Non-GDP series for Fig3 top panel
            isGDP  = strcmp('GDPC1', app.Spec.SeriesID);
            nonGDP = app.Spec.SeriesID(~isGDP);
            app.Fig3SeriesDropDown.Items = nonGDP;
            if ~isempty(nonGDP)
                if ismember('PAYEMS', nonGDP)
                    app.Fig3SeriesDropDown.Value = 'PAYEMS';
                else
                    app.Fig3SeriesDropDown.Value = nonGDP{1};
                end
            end
        end

        %------------------------------------------------------------------------------------------
        % updateFig1 — Data tab
        %   Top axes: raw observed data (Z)
        %   Bottom axes: transformed/standardized data (X_sm)
        function updateFig1(app)
            if isempty(app.OriginalResults) || isempty(app.Spec)
                return
            end

            selectedID = app.Fig1SeriesDropdown.Value;
            idx = find(strcmp(selectedID, app.Spec.SeriesID), 1);
            if isempty(idx)
                return
            end

            t = app.TimeOriginal;
            freq = app.Spec.Frequency{idx};
            if strcmp(freq, 'q')
                fmt = 'QQ yyyy';
            else
                fmt = 'mmm yyyy';
            end

            % ── Top axes: raw data ───────────────────────────────────────────────
            cla(app.Fig1AxesRaw);
            if ~isempty(app.XData)
                rawSeries = app.XData(:, idx);
                nT = min(length(t), size(app.XData, 1));
                plot(app.Fig1AxesRaw, t(1:nT), rawSeries(1:nT), ...
                    'Color', [0.13 0.47 0.71], 'LineWidth', 1.4);
            end
            title(app.Fig1AxesRaw, ['Raw observed data — ' app.Spec.SeriesName{idx}]);
            ylabel(app.Fig1AxesRaw, app.Spec.Units{idx});
            xlabel(app.Fig1AxesRaw, '');
            grid(app.Fig1AxesRaw, 'on');
            datetick(app.Fig1AxesRaw, 'x', fmt, 'keeplimits');
            app.Fig1AxesRaw.XTickLabelRotation = 45;

            % ── Bottom axes: transformed data ────────────────────────────────────
            cla(app.Fig1AxesTransformed);
            xSm = app.OriginalResults.X_sm(:, idx);
            nT  = min(length(t), length(xSm));
            plot(app.Fig1AxesTransformed, t(1:nT), xSm(1:nT), ...
                'Color', [0.13 0.47 0.71], 'LineWidth', 1.4);
            title(app.Fig1AxesTransformed, 'Transformed data (Kalman smoothed)');
            ylabel(app.Fig1AxesTransformed, app.Spec.UnitsTransformed{idx});
            xlabel(app.Fig1AxesTransformed, '');
            grid(app.Fig1AxesTransformed, 'on');
            datetick(app.Fig1AxesTransformed, 'x', fmt, 'keeplimits');
            app.Fig1AxesTransformed.XTickLabelRotation = 45;
        end

        %------------------------------------------------------------------------------------------
        % updateFig2 — Common Factor tab
        %   All standardized smoothed series as dotted grey lines
        %   Global common factor overlaid as solid black line
        function updateFig2(app)
            if isempty(app.OriginalResults) || isempty(app.Spec)
                return
            end

            Res = app.OriginalResults;
            t   = app.TimeOriginal;
            nT  = min(length(t), size(Res.x_sm, 1));

            cla(app.Fig2Axes);
            hold(app.Fig2Axes, 'on');

            % All standardized smoothed series — dotted, distinct colors
            colors = lines(size(Res.x_sm, 2));
            for i = 1:size(Res.x_sm, 2)
                plot(app.Fig2Axes, t(1:nT), Res.x_sm(1:nT, i), ...
                    'Color', colors(i,:), ...
                    'LineStyle', ':', ...
                    'LineWidth', 0.8, ...
                    'HandleVisibility', 'off');
            end

            % Global common factor — use INDPRO loading if available, else series 1
            refIdx = find(strcmp('INDPRO', app.Spec.SeriesID), 1);
            if isempty(refIdx)
                refIdx = 1;
            end
            commonFactor = Res.Z(1:nT, 1) .* Res.C(refIdx, 1);
            h = plot(app.Fig2Axes, t(1:nT), commonFactor, ...
                'k', 'LineWidth', 1.8, 'DisplayName', 'common factor');

            hold(app.Fig2Axes, 'off');
            legend(app.Fig2Axes, h, 'common factor', 'Location', 'northwest');
            legend(app.Fig2Axes, 'boxoff');
            title(app.Fig2Axes, 'Common Factor and Standardized Data');
            xlabel(app.Fig2Axes, '');
            ylabel(app.Fig2Axes, 'Standardized units');
            grid(app.Fig2Axes, 'on');
            datetick(app.Fig2Axes, 'x', 'yyyy', 'keeplimits');
            app.Fig2Axes.XTickLabelRotation = 45;
        end

        %------------------------------------------------------------------------------------------
        % updateFig3 — Projection tab
        %   Top panel: user-selected series, common factor projection vs actual
        %   Bottom panel: always GDPC1
        function updateFig3(app)
            if isempty(app.OriginalResults) || isempty(app.Spec)
                return
            end

            Res = app.OriginalResults;
            t   = app.TimeOriginal;
            nF  = size(app.Spec.Blocks, 2);

            % ── Top panel: selected series ────────────────────────────────────────
            selectedID = app.Fig3SeriesDropDown.Value;
            idx = find(strcmp(selectedID, app.Spec.SeriesID), 1);

            cla(app.Fig3AxesTop);
            if ~isempty(idx)
                nT = min(length(t), size(Res.Z, 1));

                % Common factor projection
                proj = Res.C(idx, 1:nF) * Res.Z(1:nT, 1:nF)' ...
                    .* Res.Wx(idx) + Res.Mx(idx);
                proj = proj(:);

                % Actual transformed data
                actual = Res.X_sm(1:nT, idx);
                t_obs  = ~isnan(actual);

                hold(app.Fig3AxesTop, 'on');
                plot(app.Fig3AxesTop, t(1:nT), proj, ...
                    'k', 'LineWidth', 1.4, 'DisplayName', 'common component');
                plot(app.Fig3AxesTop, t(t_obs), actual(t_obs), ...
                    'Color', [0.13 0.47 0.71], 'LineWidth', 1.2, ...
                    'DisplayName', 'data');
                hold(app.Fig3AxesTop, 'off');

                title(app.Fig3AxesTop, app.Spec.SeriesName{idx});
                ylabel(app.Fig3AxesTop, app.Spec.UnitsTransformed{idx});
                legend(app.Fig3AxesTop, 'show', 'Location', 'northwest');
                legend(app.Fig3AxesTop, 'boxoff');
                grid(app.Fig3AxesTop, 'on');
                datetick(app.Fig3AxesTop, 'x', 'yyyy', 'keeplimits');
                app.Fig3AxesTop.XTickLabelRotation = 45;
            end

            % ── Bottom panel: always GDPC1 ────────────────────────────────────────
            gdp_idx = find(strcmp('GDPC1', app.Spec.SeriesID), 1);

            cla(app.Fig3AxesBottom);
            if ~isempty(gdp_idx)
                nT = min(length(t), size(Res.Z, 1));

                proj = Res.C(gdp_idx, 1:nF) * Res.Z(1:nT, 1:nF)' ...
                    .* Res.Wx(gdp_idx) + Res.Mx(gdp_idx);
                proj = proj(:);

                actual = Res.X_sm(1:nT, gdp_idx);
                t_obs  = ~isnan(actual);

                hold(app.Fig3AxesBottom, 'on');
                plot(app.Fig3AxesBottom, t(1:nT), proj, ...
                    'k', 'LineWidth', 1.4, 'DisplayName', 'common component');
                plot(app.Fig3AxesBottom, t(t_obs), actual(t_obs), ...
                    'Color', [0.13 0.47 0.71], 'LineWidth', 1.2, ...
                    'DisplayName', 'data');
                hold(app.Fig3AxesBottom, 'off');

                title(app.Fig3AxesBottom, app.Spec.SeriesName{gdp_idx});
                ylabel(app.Fig3AxesBottom, app.Spec.UnitsTransformed{gdp_idx});
                legend(app.Fig3AxesBottom, 'show', 'Location', 'northwest');
                legend(app.Fig3AxesBottom, 'boxoff');
                grid(app.Fig3AxesBottom, 'on');
                datetick(app.Fig3AxesBottom, 'x', 'YYYY:QQ', 'keeplimits');
                app.Fig3AxesBottom.XTickLabelRotation = 45;
            end
        end

        %------------------------------------------------------------------------------------------
        % updateSpecCompGraphs — Spec Comparison tab
        %   Top: overlay of original vs modified GDP nowcast
        %   Bottom: difference bar chart
        function updateSpecCompGraphs(app)
            if isempty(app.OriginalResults) || isempty(app.UpdatedResults)
                return
            end

            try
                y1 = computeGDPProjection(app, app.OriginalResults, app.Spec);
                y2 = computeGDPProjection(app, app.UpdatedResults, app.ModifiedSpec);
                t  = app.TimeOriginal;
                n  = min(length(t), min(length(y1), length(y2)));

                % ── Overlay ───────────────────────────────────────────────────────
                cla(app.SpecCompOverlay);
                hold(app.SpecCompOverlay, 'on');

                plot(app.SpecCompOverlay, t(1:n), y1(1:n), ...
                    'Color', [0.13 0.47 0.71], 'LineWidth', 1.8, ...
                    'DisplayName', 'Original spec');
                plot(app.SpecCompOverlay, t(1:n), y2(1:n), ...
                    'Color', [0.84 0.15 0.16], 'LineWidth', 1.8, ...
                    'LineStyle', '--', 'DisplayName', 'Modified spec');

                hold(app.SpecCompOverlay, 'off');
                title(app.SpecCompOverlay, ...
                    'GDP nowcast: original vs modified specification');
                ylabel(app.SpecCompOverlay, 'Real GDP growth (ann. %)');
                xlabel(app.SpecCompOverlay, '');
                grid(app.SpecCompOverlay, 'on');
                legend(app.SpecCompOverlay, 'show', 'Location', 'northwest');
                legend(app.SpecCompOverlay, 'boxoff');
                app.SpecCompOverlay.XTickLabelRotation = 45;
                datetick(app.SpecCompOverlay, 'x', 'YYYY:QQ', 'keeplimits');

                % ── Difference ───────────────────────────────────────────────────
                cla(app.SpecCompDiff);
                bar(app.SpecCompDiff, t(1:n), y2(1:n) - y1(1:n), ...
                    'FaceColor', [0.50 0.50 0.50], 'EdgeColor', 'none');
                yline(app.SpecCompDiff, 0, 'k-', 'LineWidth', 0.5);
                title(app.SpecCompDiff, 'Difference (modified − original)');
                ylabel(app.SpecCompDiff, 'Δ ann. %');
                xlabel(app.SpecCompDiff, '');
                grid(app.SpecCompDiff, 'on');
                app.SpecCompDiff.XTickLabelRotation = 45;
                datetick(app.SpecCompDiff, 'x', 'YYYY:QQ', 'keeplimits');

            catch ME
                uialert(app.UIFigure, ME.message, 'Spec Comparison Error');
            end
        end
        function dateStr = extractVintageDate(~, filename)
            % Pulls a yyyy-mm-dd date out of a vintage filename (e.g. '2016-12-23.xls').
            % Falls back to today's date if the filename doesn't match.
            tok = regexp(filename, '\d{4}-\d{2}-\d{2}', 'match', 'once');
            if isempty(tok)
                dateStr = datestr(now, 'yyyy-mm-dd');
            else
                dateStr = tok;
            end
        end
        %------------------------------------------------------------------------------------------

        function loadDataFile(app)
            [file, path] = uigetfile({'*.xls;*.xlsx','Excel Files (*.xls, *.xlsx)'}, ...
                'Select Old Vintage Data File');

            if isequal(file,0)
                return
            end

            app.DataFilePath   = fullfile(path, file);
            app.OldVintageDate = extractVintageDate(app, file);

            try
                T = readtable(app.DataFilePath, 'Sheet', 'data');
                app.OriginalTable             = T;
                app.DataStatusLabel.Text      = ['✓ ' file];
                app.DataStatusLabel.FontColor = [0.13 0.55 0.13];
            catch ME
                app.DataStatusLabel.Text      = '✗ Load failed';
                app.DataStatusLabel.FontColor = [0.85 0.10 0.10];
                uialert(app.UIFigure, ME.message, 'Data Load Error');
            end
        end
        %------------------------------------------------------------------------------------------

        function loadNewVintageFile(app)
            [file, path] = uigetfile({'*.xls;*.xlsx','Excel Files (*.xls, *.xlsx)'}, ...
                'Select New Vintage Data File');

            if isequal(file,0)
                return
            end

            app.NewVintageFilePath = fullfile(path, file);
            app.NewVintageDate     = extractVintageDate(app, file);

            try
                readtable(app.NewVintageFilePath, 'Sheet', 'data');  % validate it opens
                app.NewVintageStatusLabel.Text      = ['✓ ' file];
                app.NewVintageStatusLabel.FontColor = [0.13 0.55 0.13];
            catch ME
                app.NewVintageStatusLabel.Text      = '✗ Load failed';
                app.NewVintageStatusLabel.FontColor = [0.85 0.10 0.10];
                uialert(app.UIFigure, ME.message, 'Data Load Error');
            end
        end

        %------------------------------------------------------------------------------------------
        function setAllSeries(app, val)
            % Set every block + series checkbox to val (1=on, 0=off), robustly.
            % Skips invalid/deleted handles so one stale handle can't abort the loop.
            for b = 1:numel(app.BlockCheckboxes)
                bCB = app.BlockCheckboxes(b);
                if isvalid(bCB)
                    bCB.Value     = val;
                    bCB.FontColor = [0.24 0.20 0.54];
                end
                if b <= numel(app.SeriesCheckboxes)
                    sCBs = app.SeriesCheckboxes{b};
                    for i = 1:numel(sCBs)
                        if isvalid(sCBs(i))
                            sCBs(i).Value = val;
                        end
                    end
                end
            end
        end
        %------------------------------------------------------------------------------------------

        function restoreRunButton(app, txt)
            if isvalid(app.RunModelButton)
                app.RunModelButton.Text   = txt;
                app.RunModelButton.Enable = 'on';
            end
        end
        %------------------------------------------------------------------------------------------
        function runNewsDecomposition(app)
            if isempty(app.OriginalResults) || isempty(app.UpdatedResults)
                return
            end
            try
                [yr, mo, ~] = datevec(app.TimeOriginal(end));
                mo_next = mo + 3;  yr_next = yr;
                if mo_next > 12, mo_next = mo_next - 12; yr_next = yr_next + 1; end
                qtr    = ceil(mo_next / 3);
                series = 'GDPC1';
                period = sprintf('%dq%d', yr_next, qtr);

                keep   = app.ModifiedKeepRows;
                XOld_k = app.XOld(:, keep);
                XNew_k = app.XNew(:, keep);

                % Capture the printed Nowcast Update + Impact Decomposition + Detail Table
                app.NowcastLog = evalc(['[y_old, y_new, news_table] = update_nowcast(' ...
                    'XOld_k, XNew_k, app.TimeOriginal, app.ModifiedSpec, app.UpdatedResults, ' ...
                    'series, period, app.OldVintageDate, app.NewVintageDate);']);

                % Release filter aligned to the columns actually passed (ModifiedSpec),
                % same rule example_Nowcast.m uses: any(isnan(old) & ~isnan(new)).
                released   = any(isnan(XOld_k) & ~isnan(XNew_k), 1);
                news_table = news_table(released(:), :);

                % Headline nowcast
                gdpRow = strcmp(app.Spec.SeriesID, 'GDPC1');
                app.NowcastValueLabel.Text = sprintf('%+.2f%%', y_new);
                app.NowcastUnitsLabel.Text = app.Spec.UnitsTransformed{gdpRow};
                app.NowcastQoQLabel.Text   = sprintf('Prior vintage: %+.2f%%', y_old);

                % Column layout now matches example_Nowcast.m (Forecast/Actual/Weight/Impact),
                % with Series ID/Name added for readability.
                app.UITable.ColumnName  = {'Series ID','Series Name','Forecast','Actual','Weight','Impact'};
                app.UITable.ColumnWidth = {110, 220, 110, 110, 110, 110};

                if isempty(news_table) || height(news_table) == 0
                    app.UITable.Data    = {'No forecast was made','','','','',''};
                    app.NewsExportTable = table();
                else
                    n     = height(news_table);
                    ids   = news_table.Properties.RowNames;
                    names = cell(n,1);
                    tableData = cell(n,6);
                    for k = 1:n
                        j = find(strcmp(ids{k}, app.Spec.SeriesID), 1);
                        if ~isempty(j), names{k} = app.Spec.SeriesName{j}; else, names{k} = ids{k}; end
                        tableData{k,1} = ids{k};
                        tableData{k,2} = names{k};
                        tableData{k,3} = round(news_table.Forecast(k), 3);
                        tableData{k,4} = round(news_table.Actual(k),   3);
                        tableData{k,5} = round(news_table.Weight(k),   4);
                        tableData{k,6} = round(news_table.Impact(k),   4);
                    end
                    app.UITable.Data = tableData;

                    app.NewsExportTable = table(ids, names, ...
                        news_table.Forecast, news_table.Actual, news_table.Weight, news_table.Impact, ...
                        'VariableNames', {'SeriesID','SeriesName','Forecast','Actual','Weight','Impact'});
                end
            catch ME
                app.UITable.Data = {['Error: ' ME.message],'','','','',''};
            end
        end

        %------------------------------------------------------------------------------------------
    
        function displaySummaryTab(app)
            dfmText = [
                "===== example_DFM.m output ====="
                ""
                splitlines(string(app.SpecLog))
                splitlines(string(app.SummaryLog))
                splitlines(string(app.OriginalLog))
                ];
            nowText = [
                "===== example_Nowcast.m output ====="
                ""
                splitlines(string(app.NowcastLog))
                ];

            app.SummaryTextArea.FontName   = 'Courier New';   % tables are space-aligned
            app.TextArea_4.FontName = 'Courier New';
            app.SummaryTextArea.Value      = cellstr(dfmText);
            app.TextArea_4.Value    = cellstr(nowText);

            app.ModelOutputLabel.Text = 'example_DFM.m output';
            app.FullLogLabel.Text      = 'example_Nowcast.m output';
        end
        %------------------------------------------------------------------------------------------
        % end of Custom METHODS
    end


    %------------------------------------------------------------------------------------------


    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            % ── Path setup ───────────────────────────────────────────────────────
            appDir = fileparts(which('app1.mlapp'));
            if isempty(appDir)
                appDir = pwd;
            end
            functionsDir = fullfile(appDir, 'functions');
            if ~isfolder(functionsDir)
                uialert(app.UIFigure, ...
                    ['Cannot find functions folder at ' functionsDir newline ...
                    'Run the app from its own directory.'], ...
                    'Path Error');
                return
            end
            addpath(functionsDir);

            % Checkbox panel — full width
            app.BlockCheckboxes  = matlab.ui.control.CheckBox.empty(1,0);
            app.SeriesCheckboxes = {};
            
            % ── Figures tab layout ────────────────────────────────────────────────


            % Fig3
            
            %app.Fig3SeriesDropDown.Position      = [70 500 250 22];
            %app.Fig3AxesTop.Position             = [10 265 740 225];
            %app.Fig3AxesBottom.Position          = [10 20  740 225];


            % Tab titles
            %app.Fig1Tab.Title     = 'Fig 1: Data';
            %app.Fig2Tab.Title     = 'Fig 2: Common Factor';
            %app.Fig3Tab.Title     = 'Fig 3: Projection';

            % ── Results tab ───────────────────────────────────────────────────────


        end

        % Button pushed function: SpecFileButton
        function LoadSpecFileButtonPushed(app, event)
            loadSpecFile(app);
        end

        % Button pushed function: LoadOldVintageButton
        function LoadOldVintageButtonPushed(app, event)
            loadDataFile(app);
        end

        % Button pushed function: RunModelButton
        function RunModelButtonPushed(app, event)
            % Button pushed function: RunModelButton
                % Disable button + show busy state; restore automatically on exit
                app.RunModelButton.Enable = 'off';
                origText = app.RunModelButton.Text;
                cleanupBtn = onCleanup(@() restoreRunButton(app, origText));

                app.RunModelButton.Text = 'Running model...';
                drawnow

                % ── 1. Estimate model ─────────────────────────────────────────────
                runModel(app);
                if isempty(app.OriginalResults)
                    return   % runModel failed or files missing; alert already shown
                end

                % ── 2. Results tab — paint immediately ────────────────────────────
                try, updateResultsTab(app); catch ME, warning('%s', ME.message); end
                app.RunModelButton.Text = 'Updating figures...';
                drawnow

                % ── 3. Logs + dropdowns ───────────────────────────────────────────
                try, updateForecastDetailsLog(app);  catch ME, warning('%s', ME.message); end
                try, populateFiguresDropdowns(app);   catch ME, warning('%s', ME.message); end

                % ── 4. Figures — paint each as it finishes ────────────────────────
                try, updateFig1(app); catch ME, warning('%s', ME.message); end
                drawnow
                try, updateFig2(app); catch ME, warning('%s', ME.message); end
                drawnow
                try, updateFig3(app); catch ME, warning('%s', ME.message); end
                drawnow
                % ── 5. News decomposition ──────────────
                app.RunModelButton.Text = 'Computing news...';
                drawnow
                runNewsDecomposition(app);

                % ── 6. Populate Summary tab text ──────────────────────────────────
                try, displaySummaryTab(app); catch ME, warning('%s', ME.message); end
          

        end

        % Button pushed function: SelectAllButton
        function SelectAllButtonPushed(app, event)
            setAllSeries(app, 1);
        end

        % Button pushed function: DeselectAllButton
        function DeselectAllButtonPushed(app, event)
            setAllSeries(app, 0);
        end

        % Value changed function: Fig1SeriesDropdown
        function Fig1SeriesDropDownChanged(app, event)
            updateFig1(app);
        end

        % Value changed function: Fig3SeriesDropDown
        function Fig3SeriesDropdownChanged(app, event)
            updateFig3(app);
        end

        % Button pushed function: SampleSpecButton
        function SampleSpecButtonPushed(app, event)
            % Load the bundled sample spec file without a file dialog
            sampleSpec = fullfile(pwd, 'Spec_US_example.xls');
            if ~isfile(sampleSpec)
                uialert(app.UIFigure, ['File not found in: ' pwd], 'File Not Found');
                return
            end
            try
                app.Spec = load_spec(sampleSpec);
                app.SpecFilePath = sampleSpec;
                populateFilterPanel(app);
                populateFiguresDropdowns(app);
                drawnow;
                [~, fname, ext] = fileparts(sampleSpec);
                app.SpecStatusLabel.Text      = ['✓ ' fname ext ' (sample)'];
                app.SpecStatusLabel.FontColor = [0.13 0.55 0.13];
                app.SpecStatusLabel.WordWrap  = 'off';
            catch ME
                uialert(app.UIFigure, ME.message, 'Error loading sample spec');
            end

        end

        % Button pushed function: SampleDataButton
        function SampleDataButtonPushed(app, event)
            sampleData = fullfile(pwd, 'data', 'US', '2016-12-16.xls');
            if ~isfile(sampleData)
                uialert(app.UIFigure, ['File not found in: ' pwd], 'File Not Found');
                return
            end
            app.DataFilePath   = sampleData;
            app.OldVintageDate = '2016-12-16';
            [~, fname, ext] = fileparts(sampleData);
            app.DataStatusLabel.Text      = ['✓ ' fname ext ' (sample)'];
            app.DataStatusLabel.FontColor = [0.13 0.55 0.13];
            app.DataStatusLabel.WordWrap  = 'off';
        end

        % Button pushed function: LoadNewVintageButton
        function LoadNewVintageButtonPushed(app, event)
            loadNewVintageFile(app);
        end

        % Button pushed function: SampleNewVintageButton
        function SampleNewVintageButtonPushed(app, event)
            sampleData = fullfile(pwd, 'data', 'US', '2016-12-23.xls');
            if ~isfile(sampleData)
                uialert(app.UIFigure, ['File not found in: ' pwd], 'File Not Found');
                return
            end
            app.NewVintageFilePath = sampleData;
            app.NewVintageDate     = '2016-12-23';
            [~, fname, ext] = fileparts(sampleData);
            app.NewVintageStatusLabel.Text      = ['✓ ' fname ext ' (sample)'];
            app.NewVintageStatusLabel.FontColor = [0.13 0.55 0.13];
            app.NewVintageStatusLabel.WordWrap  = 'off';
        end

        % Button pushed function: ExportNewsButton
        function ExportNewsButtonPushed(app, event)
            if isempty(app.NewsExportTable) || height(app.NewsExportTable) == 0
                uialert(app.UIFigure, 'Run a nowcast first — no News Table to export.', 'Nothing to export');
                return
            end
            [file, path] = uiputfile('*.xlsx', 'Export News Detail Table', 'news_detail.xlsx');
            if isequal(file, 0), return, end
            try
                writetable(app.NewsExportTable, fullfile(path, file), ...
                    'FileType', 'spreadsheet', 'AutoFitWidth', false);
                uialert(app.UIFigure, 'News table exported.', 'Export Complete', 'Icon', 'success');
            catch ME
                uialert(app.UIFigure, ME.message, 'Export Error');
            end
        end

        % Button pushed function: ExportSummarytxtButton
        function ExportSummaryTxtButtonPushed(app, event)
            if isempty(app.SpecLog) && isempty(app.OriginalLog) && isempty(app.NowcastLog)
                uialert(app.UIFigure, 'Run the model first.', 'Nothing to export');
                return
            end
            [file, path] = uiputfile('*.txt', 'Export Summary Text', 'nowcast_summary.txt');
            if isequal(file, 0), return, end
            try
                lines = [
                    "===== example_DFM.m output ====="
                    string(app.SpecLog)
                    string(app.SummaryLog)
                    string(app.OriginalLog)
                    ""
                    "===== example_Nowcast.m output ====="
                    string(app.NowcastLog)
                    ];
                writelines(lines, fullfile(path, file));
                uialert(app.UIFigure, 'Summary exported.', 'Export Complete', 'Icon', 'success');
            catch ME
                uialert(app.UIFigure, ME.message, 'Export Error');
            end
        end

        % Button pushed function: AIGenerateButton
        function AIGenerateButtonPushed(app, event)
            
        end

        % Button pushed function: AIExportButton
        function AIExportButtonPushed(app, event)
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TabGroup = uitabgroup(app.UIFigure);
            app.MainTab = uitab(app.TabGroup);
            app.MainTab.Title = 'Main';
            app.ConfigureRun = uipanel(app.MainTab);
            app.ConfigureRun.Title = 'Configure Run';
            app.RunModelButton = uibutton(app.ConfigureRun, 'push');
            app.RunModelButton.ButtonPushedFcn = createCallbackFcn(app, @RunModelButtonPushed, true);
            app.RunModelButton.Text = 'Run Model';
            app.SeriesFilterLabel = uilabel(app.ConfigureRun);
            app.SeriesFilterLabel.Text = 'Include Series';
            app.SeriesFilterNoteLabel = uilabel(app.ConfigureRun);
            app.SeriesFilterNoteLabel.Text = 'populated from spec file (uncheck to exclude from modified model)';
            app.SelectAllButton = uibutton(app.ConfigureRun, 'push');
            app.SelectAllButton.ButtonPushedFcn = createCallbackFcn(app, @SelectAllButtonPushed, true);
            app.SelectAllButton.Text = 'Select All';
            app.DeselectAllButton = uibutton(app.ConfigureRun, 'push');
            app.DeselectAllButton.ButtonPushedFcn = createCallbackFcn(app, @DeselectAllButtonPushed, true);
            app.DeselectAllButton.Text = 'Deselect All';
            app.FilterScrollPanel = uipanel(app.ConfigureRun);
            app.FilesPanel = uipanel(app.MainTab);
            app.FilesPanel.Title = 'Load Files';
            app.DataStatusLabel = uilabel(app.FilesPanel);
            app.DataStatusLabel.Text = 'Not loaded';
            app.SpecStatusLabel = uilabel(app.FilesPanel);
            app.SpecStatusLabel.Text = 'Not loaded';
            app.SpecFileButton = uibutton(app.FilesPanel, 'push');
            app.SpecFileButton.ButtonPushedFcn = createCallbackFcn(app, @LoadSpecFileButtonPushed, true);
            app.SpecFileButton.Text = 'Load Spec File';
            app.LoadOldVintageButton = uibutton(app.FilesPanel, 'push');
            app.LoadOldVintageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadOldVintageButtonPushed, true);
            app.LoadOldVintageButton.Text = 'Load Old Vintage';
            app.SampleSpecButton = uibutton(app.FilesPanel, 'push');
            app.SampleSpecButton.ButtonPushedFcn = createCallbackFcn(app, @SampleSpecButtonPushed, true);
            app.SampleSpecButton.Text = 'Use sample';
            app.SampleDataButton = uibutton(app.FilesPanel, 'push');
            app.SampleDataButton.ButtonPushedFcn = createCallbackFcn(app, @SampleDataButtonPushed, true);
            app.SampleDataButton.Text = 'Use sample';
            app.LoadNewVintageButton = uibutton(app.FilesPanel, 'push');
            app.LoadNewVintageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadNewVintageButtonPushed, true);
            app.LoadNewVintageButton.Text = 'Load New Vintage';
            app.SampleNewVintageButton = uibutton(app.FilesPanel, 'push');
            app.SampleNewVintageButton.ButtonPushedFcn = createCallbackFcn(app, @SampleNewVintageButtonPushed, true);
            app.SampleNewVintageButton.Text = 'Use sample';
            app.NewVintageStatusLabel = uilabel(app.FilesPanel);
            app.NewVintageStatusLabel.Text = 'Not loaded';
            app.GDPNowcastingAppLabel = uilabel(app.MainTab);
            app.GDPNowcastingAppLabel.Text = 'GDP Nowcasting App';
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.NowcastPanel = uipanel(app.ResultsTab);
            app.NowcastTitleLabel = uilabel(app.NowcastPanel);
            app.NowcastTitleLabel.Text = 'GDP nowcast -- --';
            app.NowcastValueLabel = uilabel(app.NowcastPanel);
            app.NowcastValueLabel.Text = '--';
            app.NowcastUnitsLabel = uilabel(app.NowcastPanel);
            app.NowcastUnitsLabel.Text = 'QoQ% annualized rate';
            app.NowcastQoQLabel = uilabel(app.NowcastPanel);
            app.NowcastQoQLabel.Text = 'QoQ Growth: --';
            app.NewsTableLabel = uilabel(app.ResultsTab);
            app.NewsTableLabel.Text = 'NEWS DETAIL TABLE';
            app.NewsTextArea = uitextarea(app.ResultsTab);
            app.NewsTextArea.Editable = 'off';
            app.UITable = uitable(app.ResultsTab);
            app.UITable.ColumnName = {'Series ID'; 'Series Name'; 'Forecast'; 'Actual'; 'Impact (%)'};
            app.UITable.RowName = {};
            app.ExportNewsButton = uibutton(app.ResultsTab, 'push');
            app.ExportNewsButton.ButtonPushedFcn = createCallbackFcn(app, @ExportNewsButtonPushed, true);
            app.ExportNewsButton.Text = 'Export News (.xlsx)';
            app.FiguresTab = uitab(app.TabGroup);
            app.FiguresTab.Title = 'Figures';
            app.TabGroup2 = uitabgroup(app.FiguresTab);
            app.Fig1Tab = uitab(app.TabGroup2);
            app.Fig1Tab.Title = 'Fig 1';
            app.Fig1AxesRaw = uiaxes(app.Fig1Tab);
            title(app.Fig1AxesRaw, 'Title')
            xlabel(app.Fig1AxesRaw, 'X')
            ylabel(app.Fig1AxesRaw, 'Y')
            zlabel(app.Fig1AxesRaw, 'Z')
            app.Fig1AxesTransformed = uiaxes(app.Fig1Tab);
            title(app.Fig1AxesTransformed, 'Title')
            xlabel(app.Fig1AxesTransformed, 'X')
            ylabel(app.Fig1AxesTransformed, 'Y')
            zlabel(app.Fig1AxesTransformed, 'Z')
            app.Fig1SeriesDropdownLabel = uilabel(app.Fig1Tab);
            app.Fig1SeriesDropdownLabel.Text = 'Series';
            app.Fig1SeriesDropdown = uidropdown(app.Fig1Tab);
            app.Fig1SeriesDropdown.ValueChangedFcn = createCallbackFcn(app, @Fig1SeriesDropDownChanged, true);
            app.Fig2Tab = uitab(app.TabGroup2);
            app.Fig2Tab.Title = 'Fig 2';
            app.Fig2Axes = uiaxes(app.Fig2Tab);
            title(app.Fig2Axes, 'Title')
            xlabel(app.Fig2Axes, 'X')
            ylabel(app.Fig2Axes, 'Y')
            zlabel(app.Fig2Axes, 'Z')
            app.Fig3Tab = uitab(app.TabGroup2);
            app.Fig3Tab.Title = 'Fig 3';
            app.Fig3AxesTop = uiaxes(app.Fig3Tab);
            title(app.Fig3AxesTop, 'Title')
            xlabel(app.Fig3AxesTop, 'X')
            ylabel(app.Fig3AxesTop, 'Y')
            zlabel(app.Fig3AxesTop, 'Z')
            app.Fig3AxesBottom = uiaxes(app.Fig3Tab);
            title(app.Fig3AxesBottom, 'Title')
            xlabel(app.Fig3AxesBottom, 'X')
            ylabel(app.Fig3AxesBottom, 'Y')
            zlabel(app.Fig3AxesBottom, 'Z')
            app.SeriesLabel = uilabel(app.Fig3Tab);
            app.SeriesLabel.Text = 'Series';
            app.Fig3SeriesDropDown = uidropdown(app.Fig3Tab);
            app.Fig3SeriesDropDown.ValueChangedFcn = createCallbackFcn(app, @Fig3SeriesDropdownChanged, true);
            app.AICopilotTab = uitab(app.TabGroup);
            app.AICopilotTab.Title = 'AI Copilot';
            app.AIPromptDropdownLabel = uilabel(app.AICopilotTab);
            app.AIPromptDropdownLabel.Text = 'Drop Down';
            app.AIPromptDropdown = uidropdown(app.AICopilotTab);
            app.AIPromptDropdown.Items = {'Select Prompt', 'Summarize the Nowcast results', 'Option 2', 'Option 3', 'Option 4'};
            app.AIPromptDropdown.Placeholder = 'Select Prompt';
            app.AIPromptDropdown.Value = 'Select Prompt';
            app.AIAPIKeyFieldLabel = uilabel(app.AICopilotTab);
            app.AIAPIKeyFieldLabel.Text = 'API key';
            app.AIAPIKeyField = uieditfield(app.AICopilotTab, 'text');
            app.AIGenerateButton = uibutton(app.AICopilotTab, 'push');
            app.AIGenerateButton.ButtonPushedFcn = createCallbackFcn(app, @AIGenerateButtonPushed, true);
            app.AIGenerateButton.Text = 'Generate';
            app.AIResponseTextAreaLabel = uilabel(app.AICopilotTab);
            app.AIResponseTextAreaLabel.Text = 'AI Resposne';
            app.AIResponseTextArea = uitextarea(app.AICopilotTab);
            app.AIResponseTextArea.Editable = 'off';
            app.AIExportButton = uibutton(app.AICopilotTab, 'push');
            app.AIExportButton.ButtonPushedFcn = createCallbackFcn(app, @AIExportButtonPushed, true);
            app.AIExportButton.Text = 'Export Response (.txt)';
            app.SummaryTable = uitab(app.TabGroup);
            app.SummaryTable.Title = 'Summary Table';
            app.ModelOutputLabel = uilabel(app.SummaryTable);
            app.ModelOutputLabel.Text = 'Model Output';
            app.SummaryTextArea = uitextarea(app.SummaryTable);
            app.SummaryTextArea.Editable = 'off';
            app.ExportSummarytxtButton = uibutton(app.SummaryTable, 'push');
            app.ExportSummarytxtButton.ButtonPushedFcn = createCallbackFcn(app, @ExportSummaryTxtButtonPushed, true);
            app.ExportSummarytxtButton.Text = 'Export Summary (.txt)';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app1

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end